classdef trajectoryPlanning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        AiutoMenu            matlab.ui.container.Menu
        InfoMenu             matlab.ui.container.Menu
        EsciMenu             matlab.ui.container.Menu
        GridLayout           matlab.ui.container.GridLayout
        panelPositions       matlab.ui.container.Panel
        labelUpdate          matlab.ui.control.Label
        buttonLoadTimes      matlab.ui.control.Button
        textFieldTimes       matlab.ui.control.EditField
        TempiLabel           matlab.ui.control.Label
        textFieldPositions   matlab.ui.control.EditField
        PosizioniLabel       matlab.ui.control.Label
        buttonLoadPositions  matlab.ui.control.Button
        panelDataMatrix      matlab.ui.container.Panel
        matrixTable          matlab.ui.control.Table
        panelMatrix          matlab.ui.container.Panel
        graphButton          matlab.ui.control.Button
        trajectoryButton     matlab.ui.control.Button
        buttonLoadMatrix     matlab.ui.control.Button
        textFieldMatrix      matlab.ui.control.EditField
        PosizioniLabel_2     matlab.ui.control.Label
        panelDataPositions   matlab.ui.container.Panel
        tableTimes           matlab.ui.control.Table
        tablePositions       matlab.ui.control.Table
    end

    
    properties (Access = public)
        positions = [];
        times = [];
        rotations = [];
        step = 0.01;
        pe = [];
        re = [];
        s = [];
        theta_vector = [];
        theta_plot = [];
        help = "L'applicativo permette la pianificazione di traiettorie su percorso assegnato " + ...
            "nello spazio operativo. L'utente dovrà fornire in ingresso posizione ed " + ...
            "orientamento dell'organo terminale nei punti di percorso e sequenza " + ...
            "dei tempi per ciascun tratto. Per l'orientamento è necessario utilizzare " + ...
            "Matrici di rotazione. La primitiva di percorso " + ...
            "utilizzata è il segmento. Si ringrazia il prof. " + ...
            "Caccavale per gli insegnamenti ricevuti.";
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: buttonLoadPositions
        function loadPositions(app, event)
            [filename,path]=uigetfile();
            if filename == 0
                figure(app.UIFigure);
                return;
            end
            app.textFieldPositions.Value=filename;
            figure(app.UIFigure);
            load([path filename], '-mat');
            %CONTROLLO SUL FILE GIUSTO
            if(~contains(filename, 'positions'))
                f = errordlg("Inserisci il file positions.mat!","Errore",'modal');
                app.textFieldPositions.Value = '';
                return;
            end
            %CONTROLLO SUI TEMPI
            if ~isempty(app.textFieldTimes.Value)
                if size(A,1) ~= size(app.times,2)
                    f = errordlg("Le dimensioni tra tempi e posizioni non coincidono","Errore",'modal');
                    app.textFieldPositions.Value = '';
                    return;
                end
            end
            %CONTROLLO SULLE ROTAZIONI
            if ~isempty(app.textFieldMatrix.Value)
                if size(A,1)*3 ~= size(app.rotations,1)
                    f = errordlg("Le dimensioni tra posizioni e matrici di rotazione non coincidono","Errore",'modal');
                    app.textFieldPositions.Value = '';
                    return;
                end
            end
            app.positions = A;
            app.tablePositions.Data = app.positions;
            app.tablePositions.RowName = 'numbered';
            if(~isempty(app.textFieldPositions.Value) && ~isempty(app.textFieldTimes.Value) && ~isempty(app.textFieldMatrix.Value))
                app.trajectoryButton.Enable = "on";
            end
            app.labelUpdate.Text = 'Posizioni caricate correttamente.';
        end

        % Button pushed function: buttonLoadTimes
        function loadTimes(app, event)
            [filename,path]=uigetfile();
            if filename == 0
                figure(app.UIFigure);
                return;
            end
            app.textFieldTimes.Value=filename;
            figure(app.UIFigure);
            load([path filename], '-mat');
            %CONTROLLO SUL FILE GIUSTO
            if(~contains(filename, 'times'))
                f = errordlg("Inserisci il file times.mat!","Errore",'modal');
                app.textFieldTimes.Value = '';
                return;
            end
            %CONTROLLO SULLE POSIZIONI
            if ~isempty(app.textFieldPositions.Value)
                if size(t,2) ~= size(app.positions,1)-1
                    f = errordlg("Le dimensioni tra tempi e posizioni non coincidono","Errore",'modal');
                    app.textFieldTimes.Value = '';
                    return;
                end
            end
            %CONTROLLO SULLE ROTAZIONI
            if ~isempty(app.textFieldMatrix.Value)
                if (size(t,2)+1)*3 ~= size(app.rotations,1)
                    f = errordlg("Le dimensioni tra tempi e matrici di rotazione non coincidono","Errore",'modal');
                    app.textFieldTimes.Value = '';
                    return;
                end
            end
            app.times = [];
            app.times(1) = 0;
            for i=2:(size(t,2)+1)
                app.times = [app.times, (app.times(i-1)+t(i-1))];
            end
            app.tableTimes.Data = t;
            c = {};
            for i=1:size(t,2)
                c(i) = cellstr(strcat("Tratto ", string(i)));
            end
            app.tableTimes.ColumnName = c;
            if(~isempty(app.textFieldPositions.Value) && ~isempty(app.textFieldTimes.Value) && ~isempty(app.textFieldMatrix.Value))
                app.trajectoryButton.Enable = "on";
            end
            app.labelUpdate.Text = 'Tempi di percorrenza caricati correttamente.';
            assignin('base','t', t);
        end

        % Button pushed function: buttonLoadMatrix
        function loadMatrix(app, event)
            [filename,path]=uigetfile();
            if filename == 0
                figure(app.UIFigure);
                return;
            end
            app.textFieldMatrix.Value=filename;
            figure(app.UIFigure);
            load([path filename], '-mat');
            %CONTROLLO SUL FILE GIUSTO
            if(~contains(filename, 'rotations'))
                f = errordlg("Inserisci il file rotations.mat!","Errore",'modal');
                app.textFieldMatrix.Value = '';
                return;                
            end
            if(mod(size(B,1), 3) ~= 0)
                f = errordlg("Le righe nel file delle matrici di rotazioni devono essere in numero multiplo di 3!","Errore",'modal');
                app.textFieldMatrix.Value = '';
                return;
            end
            %CONTROLLO SULLE POSIZIONI
            if ~isempty(app.textFieldPositions.Value)
                if size(B,1) ~= size(app.positions,1)*3
                    f = errordlg("Le dimensioni tra posizioni e matrici di rotazione non coincidono","Errore",'modal');
                    app.textFieldMatrix.Value = '';
                    return;
                end
            end
            %CONTROLLO SUI TEMPI
            if ~isempty(app.textFieldTimes.Value)
                if size(B,1) ~= size(app.times,2)*3
                    f = errordlg("Le dimensioni tra tempi e matrici di rotazione non coincidono","Errore",'modal');
                    app.textFieldMatrix.Value = '';
                    return;
                end
            end
            %CONTROLLO VALIDITA'
            count = 1;
            for i=1:3:size(B,1)-2
                R = B(i:i+2, :);
                if ~isequal(uint8(R'*R),eye(3))
                    f = errordlg(strcat('La matrice di rotazione numero ', string(count), ' non è valida'),"Errore",'modal');
                    app.textFieldMatrix.Value = '';
                    return;
                end
                count = count + 1;
            end
            app.rotations = B;
            app.matrixTable.Data = B;
            r = {};
            count = 1;
            for i=3:3:size(app.rotations,1)
                r(i-2) = cellstr(string(count));
                r(i-1) = cellstr(' ');
                r(i) = cellstr(' ');
                count = count + 1;
            end
            app.matrixTable.RowName = r;
            if(~isempty(app.textFieldPositions.Value) && ~isempty(app.textFieldTimes.Value) && ~isempty(app.textFieldMatrix.Value))
                app.trajectoryButton.Enable = "on";
            end
            app.labelUpdate.Text = 'Matrici di rotazione caricate correttamente.';
        end

        % Button pushed function: trajectoryButton
        function generateTrajectory(app, event)
            [app.pe, app.s] = trajectory_planning_position(app.positions, app.times, app.step);
            app.labelUpdate.Text = 'Traiettoria calcolata! Ora puoi visualizzare il grafico';
            assignin('base','pe', app.pe);
            [app.theta_vector, app.re, app.theta_plot] = trajectory_planning_rotation(app.rotations, app.times, app.step);
            app.graphButton.Enable = "on";
            assignin('base','re', app.re);
            assignin('base', 'theta', app.theta_vector)
        end

        % Button pushed function: graphButton
        function generateGraph(app, event)
            A = app.positions;
            fig1 = figure('Name', 'Grafico 3D della Traiettoria');
            grid on;
            title('Grafico 3D della Traiettoria');
            xlabel('Asse X');
            ylabel('Asse Y');
            zlabel('Asse Z');
            hold on;
            set(gca, 'XLim', [-1.5 7], 'YLim', [-1.5 7], 'ZLim', [-1.5 7]);
            curve = animatedline('Color', 'b');
            %CODICE PER L'ANIMAZIONE
            for i=1:size(app.pe,1)
                view(3);
                addpoints(curve, app.pe(i,1), app.pe(i,2), app.pe(i,3));
                %drawnow %DA UTILIZZARE CON UNO STEP PARI A 0.1
                drawnow limitrate;
            end
            % NEL CASO SI VOGLIA NON UTILIZZARE L'ANIMAZIONE
            % view(3)
            % plot3(pe(:,1), pe(:,2), pe(:,3))
            for i=1:size(A,1)
                scatter = scatter3(A(i,1),A(i,2),A(i,3), 'red', 'filled');
                text(A(i,1),A(i,2),A(i,3), ['Angolo ', num2str(i), ': ', num2str(app.theta_plot(i))], ...
                    'HorizontalAlignment', 'left', ...
                    'VerticalAlignment', 'top');
            end
            for i=1:size(app.re,1)
                q = quiver3((A(i,1)+A(i+1,1))/2, (A(i,2)+A(i+1,2))/2, (A(i,3)+A(i+1,3))/2, ...
                    app.re(i,1),app.re(i,2),app.re(i,3),2.5,'Color','#EDB120', 'LineWidth', 1.5);
                %q = quiver3(A(i,1), A(i,2), A(i,3), app.re(i,1),app.re(i,2),app.re(i,3),2.5,'Color','#EDB120', 'LineWidth', 1.5);
            end
            legend([curve,scatter,q], 'Traiettoria','Punti','Assi di Rotazione','Location','northwest');
            %ANDAMENTO DI THETA
            fig2 = figure('Name', 'Andamento delle Variabili nel Tempo');
            subplot(3,1,1)
            grid on;
            title('Andamento di Theta');
            ylabel('Theta');
            hold on;
            times_plot = [];
            for i=2:size(app.times,2)
                if i~=2
                    times_plot(end) = [];
                end
                j=[app.times(i-1):app.step:app.times(i)];
                times_plot = [times_plot,j];
            end
            legend_string = [];
            for i=1:size(app.times,2)-1
                factor = 1/app.step;
                t1 = (app.times(1,i)*factor)+1;
                t2 = (app.times(1,i+1)*factor);
                hold on
                plot(times_plot(t1:t2),app.theta_vector(t1:t2))
                str = strcat("Tratto ",string(i));
                legend_string = [legend_string;str];
            end
            legend(legend_string,'Location','southeast');
            subplot(3,1,2)
            plot(times_plot, app.pe(:,1))
            hold on
            plot(times_plot, app.pe(:,2))
            hold on
            plot(times_plot, app.pe(:,3))
            hold on
            grid on;
            title('Andamento delle Coordinate di Posizione');
            ylabel('Valori di Pos.');
            legend('x','y','z','Location','NorthWest');
            hold off
            subplot(3,1,3)
            grid on;
            title("Andamento dell'Ascissa Curvilinea s");
            xlabel('Tempo (in sec)');
            ylabel('s');
            legend_string = [];
            for i=1:size(app.times,2)-1
                factor = 1/app.step;
                t1 = (app.times(1,i)*factor)+1;
                t2 = (app.times(1,i+1)*factor);
                hold on
                plot(times_plot(t1:t2),app.s(t1:t2))
                str = strcat("Tratto ",string(i));
                legend_string = [legend_string;str];
            end
            legend(legend_string,'Location','NorthWest');

        end

        % Menu selected function: AiutoMenu
        function AiutoMenuSelected(app, event)
            msgbox(app.help, 'Aiuto');
        end

        % Callback function: EsciMenu, UIFigure
        function EsciMenuSelected(app, event)
            answer = questdlg('Vuoi veramente uscire?', 'Uscita', 'Si', 'No', 'Si');
            switch answer
                case 'Si'
                    closereq();
                    close all;
                case 'No'
                    figure(app.UIFigure);
            end
        end

        % Menu selected function: InfoMenu
        function InfoMenuSelected(app, event)
            msgbox("App sviluppata da Nicolas Calzone matr. 65121 e Giuseppe Ciro Riccardi matr.63956", 'Info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pianificazione della Traiettoria nello Spazio Operativo';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @EsciMenuSelected, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.AiutoMenu = uimenu(app.FileMenu);
            app.AiutoMenu.MenuSelectedFcn = createCallbackFcn(app, @AiutoMenuSelected, true);
            app.AiutoMenu.Text = 'Aiuto';
            app.InfoMenu = uimenu(app.FileMenu);
            app.InfoMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoMenuSelected, true);
            app.InfoMenu.Text = 'Info';
            app.EsciMenu = uimenu(app.FileMenu);
            app.EsciMenu.MenuSelectedFcn = createCallbackFcn(app, @EsciMenuSelected, true);
            app.EsciMenu.Text = 'Esci';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.panelDataPositions = uipanel(app.GridLayout);
            app.panelDataPositions.Title = 'Dati di Posizione e Tempi';
            app.tablePositions = uitable(app.panelDataPositions);
            app.tablePositions.ColumnName = {'x'; 'y'; 'z'};
            app.tablePositions.RowName = {};
            app.tableTimes = uitable(app.panelDataPositions);
            app.tableTimes.ColumnName = '';
            app.tableTimes.RowName = {};
            app.panelMatrix = uipanel(app.GridLayout);
            app.panelMatrix.Title = 'Matrici di Rotazione';
            app.PosizioniLabel_2 = uilabel(app.panelMatrix);
            app.PosizioniLabel_2.Text = 'Matrici di Rotazione';
            app.textFieldMatrix = uieditfield(app.panelMatrix, 'text');
            app.textFieldMatrix.Editable = 'off';
            app.buttonLoadMatrix = uibutton(app.panelMatrix, 'push');
            app.buttonLoadMatrix.ButtonPushedFcn = createCallbackFcn(app, @loadMatrix, true);
            app.buttonLoadMatrix.Text = 'Carica';
            app.trajectoryButton = uibutton(app.panelMatrix, 'push');
            app.trajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @generateTrajectory, true);
            app.trajectoryButton.Enable = 'off';
            app.trajectoryButton.Text = 'Genera Traiettoria';
            app.graphButton = uibutton(app.panelMatrix, 'push');
            app.graphButton.ButtonPushedFcn = createCallbackFcn(app, @generateGraph, true);
            app.graphButton.Enable = 'off';
            app.graphButton.Text = 'Genera Grafici';
            app.panelDataMatrix = uipanel(app.GridLayout);
            app.panelDataMatrix.Title = 'Dati delle Matrici';
            app.matrixTable = uitable(app.panelDataMatrix);
            app.matrixTable.ColumnName = {'x'; 'y'; 'z'};
            app.matrixTable.RowName = {};
            app.panelPositions = uipanel(app.GridLayout);
            app.panelPositions.Title = 'Posizioni e Tempi';
            app.buttonLoadPositions = uibutton(app.panelPositions, 'push');
            app.buttonLoadPositions.ButtonPushedFcn = createCallbackFcn(app, @loadPositions, true);
            app.buttonLoadPositions.Text = 'Carica';
            app.PosizioniLabel = uilabel(app.panelPositions);
            app.PosizioniLabel.Text = 'Posizioni';
            app.textFieldPositions = uieditfield(app.panelPositions, 'text');
            app.textFieldPositions.Editable = 'off';
            app.TempiLabel = uilabel(app.panelPositions);
            app.TempiLabel.Text = 'Tempi';
            app.textFieldTimes = uieditfield(app.panelPositions, 'text');
            app.textFieldTimes.Editable = 'off';
            app.buttonLoadTimes = uibutton(app.panelPositions, 'push');
            app.buttonLoadTimes.ButtonPushedFcn = createCallbackFcn(app, @loadTimes, true);
            app.buttonLoadTimes.Text = 'Carica';
            app.labelUpdate = uilabel(app.panelPositions);
            app.labelUpdate.Text = 'Carica i dati per generare la traiettoria.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trajectoryPlanning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end